classdef Pseudo_Generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        ResetButton     matlab.ui.control.Button
        GenerateButton  matlab.ui.control.Button
        UIAxes2         matlab.ui.control.UIAxes
        UIAxes          matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function Phim1ButtonPushed(app, event)

        end

        % Callback function
        function Phim0ButtonPushed(app, event)

        end

        % Button pushed function: GenerateButton
        function Generate(app, event)
        % Input 16-bit binary sequence (replace with your desired sequence)
        binarySequence = randi([0 1], 1, 16);

        % Parameters
        samplingRate = 44100;  % Sampling rate (in Hz)
        duration = 1;         % Duration of the signal (in seconds)
        amplitude = 1;      % Amplitude of the signal (0 to 1)

        % Generate time vector
        t = linspace(0, duration, duration * samplingRate);
        % Generate binary signal
        binarySignal = zeros(size(t));
        bitDuration = duration / 16;
        for i = 1:16
            startIdx = round((i - 1) * bitDuration * samplingRate) + 1;
            endIdx = round(i * bitDuration * samplingRate);
            binarySignal(startIdx:endIdx) = binarySequence(i);
        end

        % Scale the signal to the desired amplitude
        binarySignal = amplitude * binarySignal;
        plot(app.UIAxes,binarySignal)
        % XOR
        x1 = xor(binarySequence(12), binarySequence(16));
        x2 = xor(binarySequence(1), binarySequence(3));
        x=xor(x1,x2);
        binary_output = [binarySequence(2:16), x];
        % Generate binary signal
        binarySignal_1 = zeros(size(t));
        bitDuration = duration / 16;
        for i = 1:16
            startIdx = round((i - 1) * bitDuration * samplingRate) + 1;
            endIdx = round(i * bitDuration * samplingRate);
            binarySignal_1(startIdx:endIdx) = binary_output(i);
        end

        % Scale the signal to the desired amplitude
        binarySignal_1 = amplitude * binarySignal_1;
        % Plot the signal
        plot(app.UIAxes2,binarySignal_1);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
cla(app.UIAxes);
cla(app.UIAxes2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Original Signal'; ''})
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 1];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, {'Generated Signal'; ''})
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [0 1];
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @Generate, true);
            app.GenerateButton.Text = 'Generate';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = {'Reset'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pseudo_Generator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end