classdef Voice_record < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        PlaybackButton  matlab.ui.control.Button
        StopButton      matlab.ui.control.Button
        StartButton     matlab.ui.control.Button
        UIAxes_2        matlab.ui.control.UIAxes
        UIAxes          matlab.ui.control.UIAxes
    end

  properties (Access = private)                
        recorder
        audiodata
        player
    end
    
    methods (Access = public)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
                Fs=8000;
                app.recorder = audiorecorder(Fs, 24, 1);
                app.recorder.record(100);
                while app.recorder.isrecording()
                    %
                    pause(0.1);
                    audioData = getaudiodata(app.recorder);
                    t = 0:1/Fs:(length (audioData)-1)/Fs;
                    plot(app.UIAxes,t,audioData);
                    %
                    n = length (audioData);
                    F= 0: (n-1)*Fs/n;
                    Y = fft (audioData,n); %fast fourier transform
                    F_0 = (-n/2:n/2-1).* (Fs/n);
                    Y_0 = fftshift(Y);
                    AY_0 = abs(Y_0); 
                    plot(app.UIAxes_2,F_0,AY_0);
                    %
                    drawnow();       
                end
                app.audiodata = getaudiodata(app.recorder);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
                pause(app.recorder);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            
        end

        % Button pushed function: PlaybackButton
        function PlaybackButtonPushed(app, event)
            if ~isempty(app.audiodata)
                app.player = audioplayer(app.audiodata, app.recorder.SampleRate);
                play(app.player);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time Domain Plot of the Recorded Signal')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Frequency Domain Plot of Audio Signal')
            xlabel(app.UIAxes_2, 'Frequency (Hz)')
            ylabel(app.UIAxes_2, 'Amplitude')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start ';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PlaybackButton = uibutton(app.UIFigure, 'push');
            app.PlaybackButton.ButtonPushedFcn = createCallbackFcn(app, @PlaybackButtonPushed, true);
            app.PlaybackButton.Text = 'Playback';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Voice_record

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end