classdef linearchirpsignal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SirenGeneratorButton  matlab.ui.control.Button
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SirenGeneratorButton
        function SirenGeneratorButtonPushed(app, event)
            
            Fs = 8000;                   % 8 kHz sample rate
            T = 1/Fs;                    % Sampling period     
            tw = 0:T:4.92;               % 4.92 secs
            yw = chirp(tw,800,4.92,1700);% From 800 Hz to 1700 Hz in 4.92 secs
            soundsc(yw, Fs)
            % Plot the chirp signal
            plot(app.UIAxes,tw,yw);

            % Plot the frequency spectrum of the chirp signal
            f = linspace(0,Fs/2,length(yw)/2);
            Y = fft(yw)/length(yw);
            plot(app.UIAxes_2,f,2*abs(Y(1:length(f))));
            
            %{
            Fs = 8000;                   % Sampling rate (Hz)
            t = 0:1/Fs:5;                 % Time vector (5 seconds)
            f1 = 440 + 100*sin(2*pi*2*t);  % Frequency 1 with modulation
            f2 = 660 + 150*sin(2*pi*3*t);  % Frequency 2 with modulation
            f3 = 880 + 200*sin(2*pi*4*t);  % Frequency 3 with modulation 
    
            % Generate the siren sound
            y = sin(2*pi*f1.*t) + sin(2*pi*f2.*t) + sin(2*pi*f3.*t);
            
            % Play the sound
            soundsc(y, Fs);
            
            % Plot the time domain signal
            plot(app.UIAxes,t, y);
            
            % Plot the frequency spectrum
            f = linspace(0, Fs/2, length(y)/2);
            Y = fft(y)/length(y);
            plot(app.UIAxes_2,f, 2*abs(Y(1:length(f))));
            %}
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Linear chirp signal')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Frequency spectrum of the chirp signal')
            xlabel(app.UIAxes_2, 'Frequency (Hz)')
            ylabel(app.UIAxes_2, 'Magnitude')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.SirenGeneratorButton = uibutton(app.UIFigure, 'push');
            app.SirenGeneratorButton.ButtonPushedFcn = createCallbackFcn(app, @SirenGeneratorButtonPushed, true);
            app.SirenGeneratorButton.Text = 'Siren Generator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = linearchirpsignal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end