classdef lookuptable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ENTERTHENUMBEROFSAMPLEORSLIDETHESLIDERLabel  matlab.ui.control.Label
        NumberofsamplesEditField       matlab.ui.control.NumericEditField
        NumberofsamplesEditFieldLabel  matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        slider_value
        n
        xn 
        f = 100% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.slider_value = app.Slider.Value;
            app.NumberofsamplesEditField.Value = round(app.slider_value); % make slider and edit box linked
            app.Slider.Value = round(app.slider_value); % update the slider itself

            fs = app.slider_value * app.f;
    
            % Generate time index
            app.n = 0:1:app.slider_value-1;
            
            if app.slider_value == 0 
                xlim(app.UIAxes, [0 1])
            else
            xlim(app.UIAxes, [0 app.slider_value])
            end
            % Generate sine wave
            app.xn = sin(2*pi*app.f*app.n/fs);
            plot(app.UIAxes,app.n,app.xn,'-o');
        end

        % Value changed function: NumberofsamplesEditField
        function NumberofsamplesEditFieldValueChanged(app, event)
            app.slider_value = app.NumberofsamplesEditField.Value;
            
            fs = app.slider_value * app.f;
    
            % Generate time index
            app.n = 0:1:app.slider_value-1;
            
            % Generate sine wave
            app.xn = sin(2*pi*app.f*app.n/fs);
            plot(app.UIAxes,app.n,app.xn,'-o');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sine Wave ')
            xlabel(app.UIAxes, 'Time index, n')
            ylabel(app.UIAxes, 'Amplitude')
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1000];
            app.Slider.MajorTicks = [0 100 200 300 400 500 600 700 800 900 1000];
            app.Slider.MajorTickLabels = {'0', '100', '200', '300', '400', '500', '600', '700', '800', '900', '1000'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.NumberofsamplesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofsamplesEditFieldLabel.Text = 'Number of samples';
            app.NumberofsamplesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofsamplesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofsamplesEditFieldValueChanged, true);
            app.ENTERTHENUMBEROFSAMPLEORSLIDETHESLIDERLabel = uilabel(app.UIFigure);
            app.ENTERTHENUMBEROFSAMPLEORSLIDETHESLIDERLabel.Text = 'ENTER THE NUMBER OF SAMPLE OR SLIDE THE SLIDER';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lookuptable

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end